classdef FRRGD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InputLabel                 matlab.ui.control.Label
        OutputLabel                matlab.ui.control.Label
        rt_threshEditFieldLabel    matlab.ui.control.Label
        rt_threshEditField         matlab.ui.control.NumericEditField
        mz_threshEditFieldLabel    matlab.ui.control.Label
        mz_threshEditField         matlab.ui.control.NumericEditField
        PkpathEditFieldLabel       matlab.ui.control.Label
        PkpathEditField            matlab.ui.control.EditField
        FinalpkpathEditFieldLabel  matlab.ui.control.Label
        FinalpkpathEditField       matlab.ui.control.EditField
        ProcessButton              matlab.ui.control.Button
        StatusEditFieldLabel       matlab.ui.control.Label
        StatusEditField            matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function pk_final = delete_redundant_features_single_pk(app)
            rt_thresh=app.rt_threshEditField.Value;
            mz_thresh=app.mz_threshEditField.Value;
            pk_path=app.PkpathEditField.Value;
            load(pk_path);
            pk=sortrows(pk,1);
            [r_pk,c_pk]=size(pk);
            sim_index_cell=cell(r_pk,1);
            num=0;
            %-------------计算每个特征的相似特征
            for i=1:r_pk
                rt_cur=pk(i,1);
                mz_cur=pk(i,2);
                sim_rt_index=find(abs(pk(i,1)-pk(:,1))<=rt_thresh);
                sim_mz_index=find(abs(pk(i,2)-pk(:,2))<=mz_thresh);
                sim_i_index=i+1:1:r_pk;
                sim_index_rt_mz=intersect(sim_rt_index,sim_mz_index);
                sim_index=intersect(sim_index_rt_mz,sim_i_index);
                if(~isempty(sim_index))
                    num=num+1;
                end
                sim_index_cell(i,1)={sim_index};
                clear sim_rt_index sim_mz_index sim_index_rt_mz sim_i_index sim_index rt_cur mz_cur ;
            end
            %--------寻找连通特征
            search_or_not_tag(1:r_pk,1)=1;%1表示搜索，0表示不搜索
            num_connect=0;%连通图个数
            for i=1:r_pk
                if(search_or_not_tag(i,1)==1)
                    [connect_index]=get_connected_features(sim_index_cell,i);
                    r_connect_index=size(connect_index,1);
                    for j=1:r_connect_index
                        search_or_not_tag(connect_index(j),1)=0;
                    end
                    num_connect=num_connect+1;
                    clear connect_index ;
                end
            end
            connect_cell=cell(num_connect,1);
            search_or_not_tag(1:r_pk,1)=1;%1表示搜索，0表示不搜索
            num_connect=0;
            for i=1:r_pk
                if(search_or_not_tag(i,1)==1)
                    [connect_index]=get_connected_features(sim_index_cell,i);
                    r_connect_index=size(connect_index,1);
                    for j=1:r_connect_index
                        search_or_not_tag(connect_index(j),1)=0;
                    end
                    num_connect=num_connect+1;
                    connect_cell(num_connect,1)={connect_index};
                    clear connect_index ;
                end
            end
            %---------------找出连通关系中存在一个以上数量的
            [connect_cell_1,connect_cell_1_plus]=seperate_connect_cell(connect_cell);
            %---------------建立连通关系矩阵
            [connect_matrix_1_plus_cell,MAX_DISTANCE]=build_connect_matrix(connect_cell_1_plus,pk,rt_thresh,mz_thresh);%MAX_DISTANCE表示两相近特征距离的最大值，距离为MAX_DISTANCE+1表示两特征不相近
            %---------------根据连通关系矩阵将连通特征融合
            [pk_fuse_part]=fuse_connect_features(connect_cell_1_plus,pk,connect_matrix_1_plus_cell,MAX_DISTANCE);
            %---------------把不需要融合的找出
            pk_none_fuse_part(1:size(connect_cell_1,1),size(pk,2))=0;
            for i=1:size(connect_cell_1,1)
                pk_none_fuse_part(i,:)=pk(cell2mat(connect_cell_1(i,1)),:);
            end
            pk_final=[pk_none_fuse_part;pk_fuse_part];

        end
        
        function [connect_1_plus_matrix_cell,MAX_DISTANCE]=build_connect_matrix(~,connect_cell,pk,rt_thresh,mz_thresh)
            r_connect_cell=size(connect_cell,1);
            connect_1_plus_matrix_cell=cell(r_connect_cell,1);
            MAX_DISTANCE=1;
            %将pk中保留时间和m/z做z-score变换
            % mean_rt=mean(pk(:,1));
            % std_rt=std(pk(:,1));
            % mean_mz=mean(pk(:,2));
            % std_mz=std(pk(:,2));
            % for i=1:size(pk,1)
            %     pk(i,1)=(pk(i,1)-mean_rt)/std_rt;
            %     pk(i,2)=(pk(i,2)-mean_mz)/std_mz;
            % end
            pk(:,1)=zscore(pk(:,1));
            pk(:,2)=zscore(pk(:,2));
            %获取特征之间的最大距离，用来给连通矩阵中不相似的特征距离赋值
            for i=1:r_connect_cell
                cur_connect_features=cell2mat(connect_cell(i,1));
                r_cur_connect_features=size(cur_connect_features,1);
                for j=1:r_cur_connect_features
                    for k=1:r_cur_connect_features
                        if(j~=k)
                            rt_delta=(abs(pk(cur_connect_features(j,1),1)-pk(cur_connect_features(k,1),1)));%/rt_thresh;
                            mz_delta=(abs(pk(cur_connect_features(j,1),2)-pk(cur_connect_features(k,1),2)));%/mz_thresh;
                            if((rt_delta^2+mz_delta^2)^(1/2)>MAX_DISTANCE)
                                MAX_DISTANCE=(rt_delta^2+mz_delta^2)^(1/2);
                            end
                        end
                    end
                end
                clear cur_connect_features ;
            end
            %给连通矩阵赋值
            for i=1:r_connect_cell
                cur_connect_features=cell2mat(connect_cell(i,1));
                r_cur_connect_features=size(cur_connect_features,1);
                cur_connect_1_plus_matrix(1:r_cur_connect_features,1:r_cur_connect_features)=1;
                for j=1:r_cur_connect_features
                    for k=1:r_cur_connect_features
                        if(j~=k) 
                            if(abs(pk(cur_connect_features(j,1),1)-pk(cur_connect_features(k,1),1))<=rt_thresh && abs(pk(cur_connect_features(j,1),2)-pk(cur_connect_features(k,1),2))<=mz_thresh)
                                rt_delta=(abs(pk(cur_connect_features(j,1),1)-pk(cur_connect_features(k,1),1)));%/rt_thresh;
                                mz_delta=(abs(pk(cur_connect_features(j,1),2)-pk(cur_connect_features(k,1),2)));%/mz_thresh;
                                cur_connect_1_plus_matrix(j,k)=(rt_delta^2+mz_delta^2)^(1/2);
                            else
                                cur_connect_1_plus_matrix(j,k)=MAX_DISTANCE+1;
                            end
                        end
                    end
                end
                connect_1_plus_matrix_cell(i,1)={cur_connect_1_plus_matrix};
                clear cur_connect_features cur_connect_1_plus_matrix ;
            end
        end
        
        function [cur_fuse_pk]=fuse_connect_1_plus_features(~,cur_connect_index,pk,cur_connect_matrix,MAX_DISTANCE)
            cur_fuse_pk=[];
            if(isempty(cur_connect_index))
                cur_fuse_pk=[];
            else
                temp_cur_connect_index=cur_connect_index;
                while(~isempty(temp_cur_connect_index))   
            %         fprintf('根据temp_cur_connect_index到cur_connect_index中找出对应位置\n');
                    %根据temp_cur_connect_index到cur_connect_index中找出对应位置
                    temp_position(size(temp_cur_connect_index,1),1)=0;
                    for i=1:size(temp_cur_connect_index,1)
                        temp_position(i,1)=find(temp_cur_connect_index(i,1)==cur_connect_index(:,1));
                    end
            %         temp_position
            %         fprintf('\n');
            %         fprintf('根据temp_postion重新建立连通关系矩阵\n');
                    %根据temp_postion重新建立连通关系矩阵
                    temp_cur_connect_matrix(1:size(temp_cur_connect_index,1),1:size(temp_cur_connect_index,1))=1;
                    for m=1:size(temp_cur_connect_index,1)
                        for n=1:size(temp_cur_connect_index,1)
                            if(m~=n)
                                temp_cur_connect_matrix(m,n)=cur_connect_matrix(temp_position(m,1),temp_position(n,1));
                            end
                        end
                    end
            %         temp_cur_connect_matrix
            %         fprintf('\n');
            %         fprintf('找出cur_connect_index中边数最多的完全连通特征图\n');
                    %找出cur_connect_index中边数最多的完全连通特征图
                    [max_connect_features_set]=get_maximum_line_complete_connect_features(temp_cur_connect_index,temp_cur_connect_matrix,MAX_DISTANCE);
            %         max_connect_features_set
            %         fprintf('\n');
                    temp_cur_connect_index=setdiff(temp_cur_connect_index,max_connect_features_set);
            %         temp_cur_connect_index
            %         fprintf('\n');
            %         fprintf('根据max_connect_features_set融合峰表\n');
                    %根据max_connect_features_set融合峰表
                    temp_cur_fuse_pk(1,1:size(pk,2))=0;
                    for k=1:size(max_connect_features_set,1)
                        temp_cur_fuse_pk=temp_cur_fuse_pk+pk(max_connect_features_set(k),:);
                    end
                    temp_cur_fuse_pk=temp_cur_fuse_pk/size(max_connect_features_set,1);
                    cur_fuse_pk=[cur_fuse_pk;temp_cur_fuse_pk];
                    clear temp_postion temp_cur_connect_matrix max_connect_features_set temp_cur_fuse_pk ;
                end
            end
        end
        
        function [connect_cell_1,connect_cell_1_plus]=seperate_connect_cell(~,connect_cell)
            r_connect_cell=size(connect_cell,1);
            num_connect_1=0;
            num_connect_1_plus=0;
            for i=1:r_connect_cell
                cur_connect_features=cell2mat(connect_cell(i,1));
                if(size(cur_connect_features,1)==1)
                    num_connect_1=num_connect_1+1;
                else
                    num_connect_1_plus=num_connect_1_plus+1;
                end
            end
            connect_cell_1=cell(num_connect_1,1);
            connect_cell_1_plus=cell(num_connect_1_plus,1);
            
            num_connect_1=0;
            num_connect_1_plus=0;
            for i=1:r_connect_cell
                cur_connect_features=cell2mat(connect_cell(i,1));
                if(size(cur_connect_features,1)==1)
                    num_connect_1=num_connect_1+1;
                    connect_cell_1(num_connect_1,1)={cur_connect_features};
                else
                    num_connect_1_plus=num_connect_1_plus+1;
                    connect_cell_1_plus(num_connect_1_plus,1)={cur_connect_features};
                end
            end
        end
        
        function [pk_fuse_part]=fuse_connect_features(~,connect_cell,pk,connect_matrix_cell,MAX_DISTANCE)
            r_connect_cell=size(connect_cell,1);
            row_pk_fuse_part=0;
            for i=1:r_connect_cell%先得到pk_fuse_part行数
            %     fprintf('%d\n',i);
                cur_connect_index=cell2mat(connect_cell(i,1));
            %     cur_connect_index
                cur_connect_matrix=cell2mat(connect_matrix_cell(i,1));
            %     fprintf('\n');
            %     cur_connect_matrix
            %     fprintf('\n');
                [cur_fuse_pk]=fuse_connect_1_plus_features(cur_connect_index,pk,cur_connect_matrix,MAX_DISTANCE);
                row_pk_fuse_part=row_pk_fuse_part+size(cur_fuse_pk,1);
                clear cur_connect_index cur_connect_matrix cur_fuse_pk ;
            end
            pk_fuse_part(1:row_pk_fuse_part,1:size(pk,2))=0;
            row_pk_fuse_part=1;
            for i=1:r_connect_cell
            %     fprintf('%d\n',i);
                cur_connect_index=cell2mat(connect_cell(i,1));
            %     cur_connect_index
                cur_connect_matrix=cell2mat(connect_matrix_cell(i,1));
            %     fprintf('\n');
            %     cur_connect_matrix
            %     fprintf('\n');
                [cur_fuse_pk]=fuse_connect_1_plus_features(cur_connect_index,pk,cur_connect_matrix,MAX_DISTANCE);
                pk_fuse_part(row_pk_fuse_part:row_pk_fuse_part+size(cur_fuse_pk,1)-1,1:size(pk,2))=cur_fuse_pk;
                row_pk_fuse_part=row_pk_fuse_part+size(cur_fuse_pk,1);
                clear cur_connect_index cur_connect_matrix cur_fuse_pk ;
            end
        end
        
        function [result]=judge_complete_connect_or_not(~,temp_connect_matrix,MAX_DISTANCE)
            result=1;%保存结果
            [r,c]=size(temp_connect_matrix);
            for i=1:r
                for j=1:c
                    if(temp_connect_matrix(i,j)>MAX_DISTANCE)
                        result=0;
                        break;
                    end
                end
            end
        end
        
        function [max_connect_features_set]=get_maximum_line_complete_connect_features(~,cur_connect_index,cur_connect_matrix,MAX_DISTANCE)
            max_line_num=0;%记录完全图的最大边数
            max_line_weight=0;%记录最大完全图边的权重和
            max_connect_features_set=[];%记录边数最多的完全图特征index
            c=1:1:size(cur_connect_index,1);
            % cur_connect_index
            % c
            % fprintf('\n');
            i=size(cur_connect_index,1);
            while(i>=1)   
            %     fprintf('i=%d\n',i);
                C=combnk(c,i);
            %     C
            %     fprintf('\n');
                for j=1:size(C,1)
                    temp_connect_matrix(1:size(C(j,:),2),1:size(C(j,:)))=0;
                    %矩阵赋值
                    for m=1:size(C(j,:),2)
                        for n=1:size(C(j,:),2)
                            temp_connect_matrix(m,n)=cur_connect_matrix(C(j,m),C(j,n));
                        end
                    end
            %         temp_connect_matrix
                    if(judge_complete_connect_or_not(temp_connect_matrix,MAX_DISTANCE))%是完全图
                        temp_max_line_weight=0;
                        %计算最大完全图边权重和
                        for m=1:size(C(j,:),2)
                           for n=1:size(C(j,:),2)
                               if(m~=n)
                                   temp_max_line_weight=temp_max_line_weight+temp_connect_matrix(m,n);
                               end
                           end
                        end 
            %             temp_max_line_weight
                        %得到完全图的index
                        temp_index(size(C(j,:),2),1)=0;
                        for k=1:size(C(j,:),2)
                            temp_index(k,1)=cur_connect_index(C(j,k),1);
                        end
                        if(max_line_num<i || (max_line_num==i && temp_max_line_weight<=max_line_weight))
                            max_line_num=i;               
                            max_line_weight=temp_max_line_weight;              
                            max_connect_features_set=temp_index;%保存最大完全图的index                  
                        end
                        clear temp_index temp_max_line_weight ;
                    end
                    clear temp_connect_matrix ;
                end
                i=i-1;
                clear C ;
            end

        end
        
        function [connect_index]=get_connected_features(~,sim_index_cell,index)
            connect_index=[];%返回的所有与index相连的connect_index，若没有返回index，若有返回index和相关连的connect index
            temp_sim_index=cell2mat(sim_index_cell(index,1));
            if(~isempty(temp_sim_index))%与index相关连的connect index非空   
                if(isrow(temp_sim_index))%是行向量
                    temp_sim_index=temp_sim_index';
                end
                r_temp_sim_index=size(temp_sim_index,1);
                for i=1:r_temp_sim_index
                    [result_index]=get_connected_features(sim_index_cell,temp_sim_index(i,1));
                    connect_index=[connect_index;result_index];
                end
                connect_index=[connect_index;index];
                connect_index=unique(connect_index(:,1));
                connect_index=sortrows(connect_index,1);   
            else%与index相关连的connect index为空
                connect_index=index;
            end
        end
    end
    

    methods (Access = private)

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            [pk_final]=delete_redundant_features_single_pk(app);
            app.StatusEditField.Value='Finished';
            path_pk=app.FinalpkpathEditField.Value;
            save(path_pk,'pk_final');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input:';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output:';
            app.rt_threshEditFieldLabel = uilabel(app.UIFigure);
            app.rt_threshEditFieldLabel.Text = 'rt_thresh';
            app.rt_threshEditField = uieditfield(app.UIFigure, 'numeric');
            app.mz_threshEditFieldLabel = uilabel(app.UIFigure);
            app.mz_threshEditFieldLabel.Text = 'm/z_thresh';
            app.mz_threshEditField = uieditfield(app.UIFigure, 'numeric');
            app.PkpathEditFieldLabel = uilabel(app.UIFigure);
            app.PkpathEditFieldLabel.Text = 'Pk path';
            app.PkpathEditField = uieditfield(app.UIFigure, 'text');
            app.FinalpkpathEditFieldLabel = uilabel(app.UIFigure);
            app.FinalpkpathEditFieldLabel.Text = 'Final pk path';
            app.FinalpkpathEditField = uieditfield(app.UIFigure, 'text');
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = FRRGD

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end